classdef Automatas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConfiguracionTab               matlab.ui.container.Tab
        CONFIGURACINLabel              matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        ALTURACONTROLMANUALLabel       matlab.ui.control.Label
        AlturaManualMuelleEditFieldLabel  matlab.ui.control.Label
        AlturaManualMuelleEditField    matlab.ui.control.NumericEditField
        AlturaManualBarcoEditFieldLabel  matlab.ui.control.Label
        AlturaManualBarcoEditField     matlab.ui.control.NumericEditField
        POSICININICIALLabel            matlab.ui.control.Label
        PosicinObjetivoIncrementalCheckBox  matlab.ui.control.CheckBox
        PosicinObjetivoEditFieldLabel  matlab.ui.control.Label
        PosicinObjetivoEditField       matlab.ui.control.NumericEditField
        LMITESCARROLabel               matlab.ui.control.Label
        LmiteIzquierdoEditFieldLabel   matlab.ui.control.Label
        LmiteIzquierdoEditField        matlab.ui.control.NumericEditField
        LmiteDerechoEditFieldLabel     matlab.ui.control.Label
        LmiteDerechoEditField          matlab.ui.control.NumericEditField
        LMITESIZAJELabel               matlab.ui.control.Label
        LmiteInferiorBarcoEditFieldLabel  matlab.ui.control.Label
        LmiteInferiorBarcoEditField    matlab.ui.control.NumericEditField
        AlturaMuelleEditFieldLabel     matlab.ui.control.Label
        AlturaMuelleEditField          matlab.ui.control.NumericEditField
        AlturaCarroEditFieldLabel      matlab.ui.control.Label
        AlturaCarroEditField           matlab.ui.control.NumericEditField
        AlturaVigaTesteraEditFieldLabel  matlab.ui.control.Label
        AlturaVigaTesteraEditField     matlab.ui.control.NumericEditField
        EscenarioContenedorTab         matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        OcupacionEditFieldLabel        matlab.ui.control.Label
        OcupacionEditField             matlab.ui.control.NumericEditField
        GenerarButton                  matlab.ui.control.Button
        AceptarButton                  matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        XContenedorMuelleEditFieldLabel  matlab.ui.control.Label
        XContenedorMuelleEditField     matlab.ui.control.NumericEditField
        HDMITab                        matlab.ui.container.Tab
        HDMILabel                      matlab.ui.control.Label
        PulsadordeEmergenciaSwitchLabel  matlab.ui.control.Label
        PulsadordeEmergenciaSwitch     matlab.ui.control.Switch
        PulsadordeEmergenciaSwitch_2Label  matlab.ui.control.Label
        PulsadordeEmergenciaSwitch_2   matlab.ui.control.Switch
        PulsadordeEmergenciaSwitch_3Label  matlab.ui.control.Label
        PulsadordeEmergenciaSwitch_3   matlab.ui.control.Switch
        ModoSwitchLabel                matlab.ui.control.Label
        ModoSwitch                     matlab.ui.control.RockerSwitch
        TomarButton                    matlab.ui.control.Button
        LiberarButton                  matlab.ui.control.Button
        XActualEditFieldLabel          matlab.ui.control.Label
        XActualEditField               matlab.ui.control.EditField
        YActualEditFieldLabel          matlab.ui.control.Label
        YActualEditField               matlab.ui.control.EditField
    end

    
    properties (Access = public)
        x_actual; % Description
        y_actuak; % Description
        Cargado; % Description
    end
    
    methods (Access = public)
        
        function actualizarPosiciones(app, in1,in2,in3)
            % Create an object that gets the run-time value of the specified block
            % Update the GUI accordingly. In this case a the value of an EditField is being updated.
            app.XActualEditField.Value = num2str(in1);
            app.YActualEditField.Value = num2str(in2);
            if in3==1
                app.EditField.Value="Cargado";
            elseif in3==-1
                app.EditField.Value="Descargado";
            end
        end
        
    end
    

    methods (Access = private)

        % Callback function
        function StartButtonValueChanged(app, event)
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            set_param('Automata' ,'SimulationCommand','pause');
            assignin('base','x_izq',app.LmiteIzquierdoEditField.Value);
            assignin('base','x_der',app.LmiteDerechoEditField.Value);
            assignin('base','PObjetivo_incremental',app.PosicinObjetivoIncrementalCheckBox.Value);
            assignin('base','PObjetivo_fijo',app.PosicinObjetivoEditField.Value);
            assignin('base','Y_inf_barco',app.LmiteInferiorBarcoEditField.Value);
            assignin('base','Y_muelle',app.AlturaMuelleEditField.Value);
            assignin('base','Y_t0',app.AlturaCarroEditField.Value);
            assignin('base','Y_sb',app.AlturaVigaTesteraEditField.Value);
            assignin('base','AlturaInicialMuelle',app.AlturaManualMuelleEditField.Value);
            assignin('base','AlturaInicialBarco',app.AlturaManualBarcoEditField.Value);
            assignin('base','Y_max',40);
            evalin('base','Variables');
            Automata;
            set_param('Automata','SimulationCommand','update');
            set_param('Automata','SimulationCommand','continue');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set_param('Automata','SimulationCommand','stop');
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            hold(app.UIAxes,'off');
            l_cont=2.5;
            lim_xi=-30+l_cont/2;
            lim_xd=0-l_cont/2;
            ml_min=2000;
            ml_max=50000;
            base_x=[-38 0 0];
            base_y=[0 0 -30];
            barco_x=[5 5 50 50];
            barco_y=[15 -20 -20 15];
            mar_x1=[0 5];
            mar_y1=[-5 -5];
            mar_x2=[50 68];
            mar_y2=[-5 -5];
            carro_x=[-38 68];
            carro_y=[45 45];
            capacidad_lateral=((barco_x(1,3)-barco_x(1,2))/l_cont);
            capacidad_vertical=((25-barco_y(1,2))/l_cont);
            %% Escenario aleatorio de contenedores en muelle y barco
            capacidad_Total=capacidad_lateral*capacidad_vertical;
            ocupacion=floor(app.OcupacionEditField.Value*capacidad_Total);
            %Creacion de contenedor en muelle
            cont_muelle_x=app.XContenedorMuelleEditField.Value+l_cont/2;
            [cont_x,cont_y]=contenedor(cont_muelle_x,1.25,l_cont);
            cont_muelle_x=(30+(cont_muelle_x-1.25))/0.5;
            cont_muelle_y=l_cont;
            cont_muelle_masa=ml_min+(ml_max-ml_min)*rand();
            plot(app.UIAxes,cont_x,cont_y,'k-');
            hold(app.UIAxes);
            location=zeros(3,ocupacion);
            i=1;
            while (i<=ocupacion)
                cont_x=randi([1 capacidad_lateral]);
                cont_y=randi([1 capacidad_vertical]);
                cont_masa=ml_min+(ml_max-ml_min)*rand();
                if cont_y==1
                    ind_y=0;
                else
                    ind_y=cont_y-1;
                end
                if(ind_y==0 && CheckContenedor(cont_x,cont_y,location(1:2,:))==0)
                    location(:,i)=[cont_x;cont_y;cont_masa];
                    [cont_x,cont_y]=contenedor(5+(cont_x-1)*l_cont+l_cont/2,-20+cont_y*l_cont/2,l_cont);
                    plot(app.UIAxes,cont_x,cont_y,'m-')
                    %                     hold(app.UIAxes)
                    i=i+1;
                elseif(ind_y~=0 && CheckContenedor(cont_x,cont_y,location(1:2,:))==0 && CheckContenedor(cont_x,ind_y,location(1:2,:))==1)
                    location(:,i)=[cont_x;cont_y;cont_masa];
                    [cont_x,cont_y]=contenedor(5+(cont_x-1)*l_cont+l_cont/2,-20+(cont_y-1)*l_cont+l_cont/2,l_cont);
                    plot(app.UIAxes,cont_x,cont_y,'m-')
                    %                     hold(app.UIAxes)
                    i=i+1;
                else
                end
                
            end
            %% Graficar
            plot(app.UIAxes,base_x,base_y,'k-',barco_x,barco_y,'g-',mar_x1,mar_y1,'b-',mar_x2,mar_y2,'b-',carro_x,carro_y,'k-');
            app.UIAxes.YLim = [-30 50];
            app.UIAxes.XLim=[-30 60];
            [temp, order] = sort(location(1,:));
            contenedores_ordenados = location(:,order);
            barco_contenedores=zeros(18,18);
            length(contenedores_ordenados)
            for i=1:length(contenedores_ordenados)
                barco_contenedores(contenedores_ordenados(1,i),contenedores_ordenados(2,i))=contenedores_ordenados(3,i);
            end
            barco_contenedores=barco_contenedores';
            barcos_envolvente=zeros(1,18);
            for j=1:length(barco_contenedores)
                temp=find(barco_contenedores(:,j)==0);
                if(temp~=0)
                    barcos_envolvente(1,j)=temp(1)-1;
                else
                    barcos_envolvente(1,j)=18;
                end
            end
            envolvente=zeros(1,90);
            for i=1:length(barcos_envolvente)
                for j=1:6
                    envolvente(1,(i-1)*5+j)=barcos_envolvente(i).*2.5-20;
                end
            end
            x=linspace(-30,50,161);
            y=zeros(1,161);
            for i=1:67
                y(i)=0;
            end
            for i=68:70
                y(i)=15;
            end
            
            for i=71:length(y)
                y(i)=envolvente(i-70);
            end
            for i=1:5
                y(cont_muelle_x+i)=cont_muelle_y;
            end
            stairs(app.UIAxes,x,y,'r')
            
            envolvente_total(1,:)=x;
            envolvente_total(2,:)=y;
            txt = {'Posicion Contenedor X: ' num2str(x(cont_muelle_x+1)+1.25) '','Posicion Contenedor Y: 2.5'};
            text(app.UIAxes,-30,35,txt)
            assignin('base','envolvente',envolvente_total);
            assignin('base','contenedores',barco_contenedores);
            assignin('base','CargaDescarga',0);
            assignin('base','cont_muelle_masa',cont_muelle_masa);
            
            
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfiguracionTab = uitab(app.TabGroup);
            app.ConfiguracionTab.Title = 'Configuracion';
            app.CONFIGURACINLabel = uilabel(app.ConfiguracionTab);
            app.CONFIGURACINLabel.Text = 'CONFIGURACIÓN';
            app.StartButton = uibutton(app.ConfiguracionTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ConfiguracionTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ALTURACONTROLMANUALLabel = uilabel(app.ConfiguracionTab);
            app.ALTURACONTROLMANUALLabel.Text = 'ALTURA CONTROL MANUAL';
            app.AlturaManualMuelleEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.AlturaManualMuelleEditFieldLabel.Text = 'Altura Manual Muelle';
            app.AlturaManualMuelleEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.AlturaManualMuelleEditField.Value = 2.5;
            app.AlturaManualBarcoEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.AlturaManualBarcoEditFieldLabel.Text = 'Altura Manual Barco';
            app.AlturaManualBarcoEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.AlturaManualBarcoEditField.Value = 2.5;
            app.POSICININICIALLabel = uilabel(app.ConfiguracionTab);
            app.POSICININICIALLabel.Text = 'POSICIÓN INICIAL';
            app.PosicinObjetivoIncrementalCheckBox = uicheckbox(app.ConfiguracionTab);
            app.PosicinObjetivoIncrementalCheckBox.Text = 'Posición Objetivo Incremental';
            app.PosicinObjetivoEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.PosicinObjetivoEditFieldLabel.Text = 'Posición Objetivo';
            app.PosicinObjetivoEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.PosicinObjetivoEditField.Value = -30;
            app.LMITESCARROLabel = uilabel(app.ConfiguracionTab);
            app.LMITESCARROLabel.Text = 'LÍMITES CARRO';
            app.LmiteIzquierdoEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.LmiteIzquierdoEditFieldLabel.Text = 'Límite Izquierdo ';
            app.LmiteIzquierdoEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.LmiteIzquierdoEditField.Value = -30;
            app.LmiteDerechoEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.LmiteDerechoEditFieldLabel.Text = 'Límite Derecho';
            app.LmiteDerechoEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.LmiteDerechoEditField.Value = 50;
            app.LMITESIZAJELabel = uilabel(app.ConfiguracionTab);
            app.LMITESIZAJELabel.Text = 'LÍMITES IZAJE';
            app.LmiteInferiorBarcoEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.LmiteInferiorBarcoEditFieldLabel.Text = 'Límite Inferior Barco';
            app.LmiteInferiorBarcoEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.LmiteInferiorBarcoEditField.Value = -20;
            app.AlturaMuelleEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.AlturaMuelleEditFieldLabel.Text = 'Altura Muelle';
            app.AlturaMuelleEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.AlturaCarroEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.AlturaCarroEditFieldLabel.Text = 'Altura Carro';
            app.AlturaCarroEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.AlturaCarroEditField.Value = 45;
            app.AlturaVigaTesteraEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.AlturaVigaTesteraEditFieldLabel.Text = 'Altura Viga Testera';
            app.AlturaVigaTesteraEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.AlturaVigaTesteraEditField.Value = 15;
            app.EscenarioContenedorTab = uitab(app.TabGroup);
            app.EscenarioContenedorTab.Title = 'Escenario Contenedor';
            app.UIAxes = uiaxes(app.EscenarioContenedorTab);
            title(app.UIAxes, 'Escenario Contenedores')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.OcupacionEditFieldLabel = uilabel(app.EscenarioContenedorTab);
            app.OcupacionEditFieldLabel.Text = 'Ocupacion';
            app.OcupacionEditField = uieditfield(app.EscenarioContenedorTab, 'numeric');
            app.GenerarButton = uibutton(app.EscenarioContenedorTab, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.AceptarButton = uibutton(app.EscenarioContenedorTab, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.EditField = uieditfield(app.EscenarioContenedorTab, 'text');
            app.XContenedorMuelleEditFieldLabel = uilabel(app.EscenarioContenedorTab);
            app.XContenedorMuelleEditFieldLabel.Text = 'X Contenedor Muelle';
            app.XContenedorMuelleEditField = uieditfield(app.EscenarioContenedorTab, 'numeric');
            app.XContenedorMuelleEditField.Limits = [-30 -2.5];
            app.XContenedorMuelleEditField.Value = -2.5;
            app.HDMITab = uitab(app.TabGroup);
            app.HDMITab.Title = 'HDMI';
            app.HDMILabel = uilabel(app.HDMITab);
            app.HDMILabel.Text = 'HDMI';
            app.PulsadordeEmergenciaSwitchLabel = uilabel(app.HDMITab);
            app.PulsadordeEmergenciaSwitchLabel.Text = 'Pulsador de Emergencia';
            app.PulsadordeEmergenciaSwitch = uiswitch(app.HDMITab, 'slider');
            app.PulsadordeEmergenciaSwitch_2Label = uilabel(app.HDMITab);
            app.PulsadordeEmergenciaSwitch_2Label.Text = 'Pulsador de Emergencia';
            app.PulsadordeEmergenciaSwitch_2 = uiswitch(app.HDMITab, 'slider');
            app.PulsadordeEmergenciaSwitch_3Label = uilabel(app.HDMITab);
            app.PulsadordeEmergenciaSwitch_3Label.Text = 'Pulsador de Emergencia';
            app.PulsadordeEmergenciaSwitch_3 = uiswitch(app.HDMITab, 'slider');
            app.ModoSwitchLabel = uilabel(app.HDMITab);
            app.ModoSwitchLabel.Text = 'Modo';
            app.ModoSwitch = uiswitch(app.HDMITab, 'rocker');
            app.ModoSwitch.Items = {'Automatico', 'Manual'};
            app.ModoSwitch.Value = 'Automatico';
            app.TomarButton = uibutton(app.HDMITab, 'push');
            app.TomarButton.Text = 'Tomar';
            app.LiberarButton = uibutton(app.HDMITab, 'push');
            app.LiberarButton.Text = 'Liberar';
            app.XActualEditFieldLabel = uilabel(app.HDMITab);
            app.XActualEditFieldLabel.Text = 'X Actual';
            app.XActualEditField = uieditfield(app.HDMITab, 'text');
            app.YActualEditFieldLabel = uilabel(app.HDMITab);
            app.YActualEditFieldLabel.Text = 'Y Actual';
            app.YActualEditField = uieditfield(app.HDMITab, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Automatas

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end